classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        Soal1Tab                 matlab.ui.container.Tab
        InputMaskTextAreaLabel   matlab.ui.control.Label
        srcEditField             matlab.ui.control.EditField
        PilihGambarButton        matlab.ui.control.Button
        InputMaskTextArea        matlab.ui.control.TextArea
        MaskinvalidLabel         matlab.ui.control.Label
        srcEditFieldLabel        matlab.ui.control.Label
        UIAxes_Input             matlab.ui.control.UIAxes
        UIAxes_Output            matlab.ui.control.UIAxes
        UIAxes_Matlab            matlab.ui.control.UIAxes
        Soal2Tab                 matlab.ui.container.Tab
        nEditField2_2Label       matlab.ui.control.Label
        nEditField2Label         matlab.ui.control.Label
        srcEditField2            matlab.ui.control.EditField
        PilihGambarButton2       matlab.ui.control.Button
        OperationDropDown2       matlab.ui.control.DropDown
        nEditField2              matlab.ui.control.NumericEditField
        sigmaEditField2          matlab.ui.control.NumericEditField
        D0EditField2             matlab.ui.control.NumericEditField
        nEditField2_2            matlab.ui.control.NumericEditField
        sigmaEditField2Label     matlab.ui.control.Label
        D0EditField2Label        matlab.ui.control.Label
        OperationDropDown2Label  matlab.ui.control.Label
        srcEditField2_Label      matlab.ui.control.Label
        UIAxes2_Input            matlab.ui.control.UIAxes
        UIAxes2_Output           matlab.ui.control.UIAxes
        Soal3Tab                 matlab.ui.container.Tab
        srcEditField3            matlab.ui.control.EditField
        PilihGambarButton3       matlab.ui.control.Button
        OperationDropDown3       matlab.ui.control.DropDown
        D0EditField3             matlab.ui.control.NumericEditField
        D0EditField3Label        matlab.ui.control.Label
        nEditField3              matlab.ui.control.NumericEditField
        nEditField3Label         matlab.ui.control.Label
        OperationDropDown3Label  matlab.ui.control.Label
        srcEditField3Label       matlab.ui.control.Label
        UIAxes3_Input            matlab.ui.control.UIAxes
        UIAxes3_Output           matlab.ui.control.UIAxes
        Soal4Tab                 matlab.ui.container.Tab
        Soal5Tab                 matlab.ui.container.Tab
        Soal6Tab                 matlab.ui.container.Tab
        Soal7Tab                 matlab.ui.container.Tab
    end

    
    methods (Access = private)
        function [img, fullPath] = browseImage(app)
           [file, path] = uigetfile('*.png;*.jpg;*.bmp;*.tif', 'Pilih Gambar');

            
            if isequal(file, 0)  % User clicked cancel
                img = [];        % Return empty image
                fullPath = '';   % Return empty path
            else
                fullPath = fullfile(path, file);  % Concatenate path and file
                img = imread(fullPath);            % Read the image file
            end

            drawnow;
            figure(app.UIFigure)
        end
        
        function section1_apply_convolution(app)
            if app.srcEditField.Value == ""
                cla(app.UIAxes_Output, 'reset');
                cla(app.UIAxes_Matlab, 'reset');
            else
                [mask, maskValid] = parse_mask_input(app.InputMaskTextArea.Value);
                if ~maskValid
                    app.MaskinvalidLabel.Visible = 'on';
                    return;
                end
                app.MaskinvalidLabel.Visible = 'off';
                img = imread(app.srcEditField.Value);
                conv_img = image_convolution(img, mask);

                mask_sum = sum(mask(:));
                matlab_conv_img = imfilter(im2double(img), mask, 'replicate', 'conv');
                if mask_sum ~= 0
                    matlab_conv_img = matlab_conv_img / mask_sum;
                end
                matlab_conv_img = im2uint8(matlab_conv_img);

                imshow(conv_img, 'Parent', app.UIAxes_Output);
                imshow(matlab_conv_img, 'Parent', app.UIAxes_Matlab);
            end
        end

        function transformed_img = section2_get_transformation(app, img)
            operation_value = app.OperationDropDown2.Value;
            switch operation_value
                case 'Mean Filter'
                    n_value = app.nEditField2.Value;
    
                    transformed_img = mean_filter(img, n_value);
                case 'Gaussian Filter'
                    n_value = app.nEditField2.Value;
                    sigma_value = app.sigmaEditField2.Value;
    
                    transformed_img = gaussian_filter(img, n_value, sigma_value);
                case 'ILPF'
                    D0_value = app.D0EditField2.Value;
    
                    transformed_img = ILPF(img, D0_value);
                case 'GLPF'
                    D0_value = app.D0EditField2.Value;
    
                    transformed_img = GLPF(img, D0_value);
                case 'BLPF'
                    D0_value = app.D0EditField2.Value;
                    n_value = app.nEditField2_2.Value;
    
                    transformed_img = BLPF(img, D0_value, n_value);
            end
        end
    
        function section2_apply_transformation(app)
            if app.srcEditField2.Value == ""
                cla(app.UIAxes2_Output, 'reset');
            else
                img = imread(app.srcEditField2.Value);
                img = section2_get_transformation(app, img);
                imshow(img, 'Parent', app.UIAxes2_Output);
            end
        end

        function transformed_img = section3_get_transformation(app, img)
            operation_value = app.OperationDropDown3.Value;
            switch operation_value
                case 'IHPF'
                    D0_value = app.D0EditField3.Value;
    
                    transformed_img = IHPF(img, D0_value);
                case 'GHPF'
                    D0_value = app.D0EditField3.Value;
    
                    transformed_img = GHPF(img, D0_value);
                case 'BHPF'
                    D0_value = app.D0EditField3.Value;
                    n_value = app.nEditField3.Value;
    
                    transformed_img = BHPF(img, D0_value, n_value);
            end
        end
    
        function section3_apply_transformation(app)
            if app.srcEditField3.Value == ""
                cla(app.UIAxes3_Output, 'reset');
            else
                img = imread(app.srcEditField3.Value);
                img = section3_get_transformation(app, img);
                imshow(img, 'Parent', app.UIAxes3_Output);
            end
        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PilihGambarButton
        function PilihGambarButtonPushed(app, event)
            [img, fullPath] = browseImage(app);
            app.srcEditField.Value = fullPath;
            imshow(img, 'Parent', app.UIAxes_Input);
            section1_apply_convolution(app);
        end

        % Value changed function: InputMaskTextArea
        function InputMaskTextAreaValueChanged(app, event)
            section1_apply_convolution(app);
        end

        % Button pushed function: PilihGambarButton2
        function PilihGambarButton2Pushed(app, event)
            [img, fullPath] = browseImage(app);
            app.srcEditField2.Value = fullPath;
            imshow(img, 'Parent', app.UIAxes2_Input);
            section2_apply_transformation(app);
        end

        % Value changed function: OperationDropDown2
        function OperationDropDown2ValueChanged(app, event)
            value = app.OperationDropDown2.Value;
                
            app.nEditField2.Visible = 'off';
            app.nEditField2_2Label.Visible = 'off';
            app.sigmaEditField2.Visible = 'off';
            app.sigmaEditField2Label.Visible = 'off';
            app.D0EditField2.Visible = 'off';
            app.D0EditField2Label.Visible = 'off';
            app.nEditField2_2.Visible = 'off';
            app.nEditField2Label.Visible = 'off';
            
            switch value
                case 'Mean Filter'
                    app.nEditField2.Visible = 'on';
                    app.nEditField2_2Label.Visible = 'on';
            
                    app.nEditField2.Value = 1;
                case 'Gaussian Filter'
                    app.nEditField2.Visible = 'on';
                    app.nEditField2_2Label.Visible = 'on';
                    app.sigmaEditField2.Visible = 'on';
                    app.sigmaEditField2Label.Visible = 'on';
            
                    app.nEditField2.Value = 1;
                    app.sigmaEditField2.Value = 3;
                case 'ILPF'
                    app.D0EditField2.Visible = 'on';
                    app.D0EditField2Label.Visible = 'on';
            
                    app.D0EditField2.Value = 50;
                case 'GLPF'
                    app.D0EditField2.Visible = 'on';
                    app.D0EditField2Label.Visible = 'on';
            
                    app.D0EditField2.Value = 50;
                case 'BLPF'
                    app.D0EditField2.Visible = 'on';
                    app.D0EditField2Label.Visible = 'on';
                    app.nEditField2_2.Visible = 'on';
                    app.nEditField2_2Label.Visible = 'on';
            
                    app.D0EditField2.Value = 50;
                    app.nEditField2_2.Value = 1;
            end
    
            section2_apply_transformation(app);
        end

        % Value changed function: D0EditField2, nEditField2, 
        % ...and 2 other components
        function Section2EditFieldValueChanged(app, event)
            section2_apply_transformation(app);
        end

        % Button pushed function: PilihGambarButton3
        function PilihGambarButton3Pushed(app, event)
            [img, fullPath] = browseImage(app);
            app.srcEditField3.Value = fullPath;
            imshow(img, 'Parent', app.UIAxes3_Input);
            section3_apply_transformation(app);
        end

        % Value changed function: OperationDropDown3
        function OperationDropDown3ValueChanged(app, event)
            value = app.OperationDropDown3.Value;
                
            app.D0EditField3.Visible = 'off';
            app.D0EditField3Label.Visible = 'off';
            app.nEditField3.Visible = 'off';
            app.nEditField3Label.Visible = 'off';
            
            switch value
                case 'IHPF'
                    app.D0EditField3.Visible = 'on';
                    app.D0EditField3Label.Visible = 'on';
            
                    app.D0EditField3.Value = 50;
                case 'GHPF'
                    app.D0EditField3.Visible = 'on';
                    app.D0EditField3Label.Visible = 'on';
            
                    app.D0EditField3.Value = 50;
                case 'BHPF'
                    app.D0EditField3.Visible = 'on';
                    app.D0EditField3Label.Visible = 'on';
                    app.nEditField3.Visible = 'on';
                    app.nEditField3Label.Visible = 'on';
            
                    app.D0EditField3.Value = 50;
                    app.nEditField3.Value = 1;
            end
    
            section3_apply_transformation(app);
        end

        % Value changed function: D0EditField3
        function Section3EditFieldValueChanged(app, event)
            section3_apply_transformation(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Soal1Tab = uitab(app.TabGroup);
            app.Soal1Tab.Title = 'Soal 1';
            app.UIAxes_Matlab = uiaxes(app.Soal1Tab);
            title(app.UIAxes_Matlab, 'Matlab')
            app.UIAxes_Output = uiaxes(app.Soal1Tab);
            title(app.UIAxes_Output, 'Output')
            app.UIAxes_Input = uiaxes(app.Soal1Tab);
            title(app.UIAxes_Input, 'Input')
            app.srcEditFieldLabel = uilabel(app.Soal1Tab);
            app.srcEditFieldLabel.Text = 'src';
            app.MaskinvalidLabel = uilabel(app.Soal1Tab);
            app.MaskinvalidLabel.Visible = 'off';
            app.MaskinvalidLabel.Text = 'Mask invalid';
            app.InputMaskTextArea = uitextarea(app.Soal1Tab);
            app.InputMaskTextArea.ValueChangedFcn = createCallbackFcn(app, @InputMaskTextAreaValueChanged, true);
            app.InputMaskTextArea.Value = {'0 -1 0'; '-1 4 -1'; '0 -1 0'};
            app.PilihGambarButton = uibutton(app.Soal1Tab, 'push');
            app.PilihGambarButton.ButtonPushedFcn = createCallbackFcn(app, @PilihGambarButtonPushed, true);
            app.PilihGambarButton.Text = 'Pilih Gambar';
            app.srcEditField = uieditfield(app.Soal1Tab, 'text');
            app.InputMaskTextAreaLabel = uilabel(app.Soal1Tab);
            app.InputMaskTextAreaLabel.Text = 'Input Mask';
            app.Soal2Tab = uitab(app.TabGroup);
            app.Soal2Tab.Title = 'Soal 2';
            app.UIAxes2_Output = uiaxes(app.Soal2Tab);
            title(app.UIAxes2_Output, 'Output')
            app.UIAxes2_Input = uiaxes(app.Soal2Tab);
            title(app.UIAxes2_Input, 'Input')
            app.srcEditField2_Label = uilabel(app.Soal2Tab);
            app.srcEditField2_Label.Text = 'src';
            app.OperationDropDown2Label = uilabel(app.Soal2Tab);
            app.OperationDropDown2Label.Text = 'Operation';
            app.D0EditField2Label = uilabel(app.Soal2Tab);
            app.D0EditField2Label.Visible = 'off';
            app.D0EditField2Label.Text = 'D0';
            app.sigmaEditField2Label = uilabel(app.Soal2Tab);
            app.sigmaEditField2Label.Visible = 'off';
            app.sigmaEditField2Label.Text = 'sigma';
            app.nEditField2_2 = uieditfield(app.Soal2Tab, 'numeric');
            app.nEditField2_2.RoundFractionalValues = 'on';
            app.nEditField2_2.ValueDisplayFormat = '%g';
            app.nEditField2_2.AllowEmpty = 'on';
            app.nEditField2_2.ValueChangedFcn = createCallbackFcn(app, @Section2EditFieldValueChanged, true);
            app.nEditField2_2.Visible = 'off';
            app.nEditField2_2.Value = 1;
            app.D0EditField2 = uieditfield(app.Soal2Tab, 'numeric');
            app.D0EditField2.RoundFractionalValues = 'on';
            app.D0EditField2.ValueDisplayFormat = '%g';
            app.D0EditField2.AllowEmpty = 'on';
            app.D0EditField2.ValueChangedFcn = createCallbackFcn(app, @Section2EditFieldValueChanged, true);
            app.D0EditField2.Visible = 'off';
            app.D0EditField2.Value = 50;
            app.sigmaEditField2 = uieditfield(app.Soal2Tab, 'numeric');
            app.sigmaEditField2.RoundFractionalValues = 'on';
            app.sigmaEditField2.ValueDisplayFormat = '%g';
            app.sigmaEditField2.AllowEmpty = 'on';
            app.sigmaEditField2.ValueChangedFcn = createCallbackFcn(app, @Section2EditFieldValueChanged, true);
            app.sigmaEditField2.Visible = 'off';
            app.sigmaEditField2.Value = 3;
            app.nEditField2 = uieditfield(app.Soal2Tab, 'numeric');
            app.nEditField2.RoundFractionalValues = 'on';
            app.nEditField2.ValueDisplayFormat = '%g';
            app.nEditField2.AllowEmpty = 'on';
            app.nEditField2.ValueChangedFcn = createCallbackFcn(app, @Section2EditFieldValueChanged, true);
            app.nEditField2.Value = 1;
            app.OperationDropDown2 = uidropdown(app.Soal2Tab);
            app.OperationDropDown2.Items = {'Mean Filter', 'Gaussian Filter', 'ILPF', 'GLPF', 'BLPF'};
            app.OperationDropDown2.ValueChangedFcn = createCallbackFcn(app, @OperationDropDown2ValueChanged, true);
            app.OperationDropDown2.Value = 'Mean Filter';
            app.PilihGambarButton2 = uibutton(app.Soal2Tab, 'push');
            app.PilihGambarButton2.ButtonPushedFcn = createCallbackFcn(app, @PilihGambarButton2Pushed, true);
            app.PilihGambarButton2.Text = 'Pilih Gambar';
            app.srcEditField2 = uieditfield(app.Soal2Tab, 'text');
            app.nEditField2Label = uilabel(app.Soal2Tab);
            app.nEditField2Label.Text = 'n';
            app.nEditField2_2Label = uilabel(app.Soal2Tab);
            app.nEditField2_2Label.Visible = 'off';
            app.nEditField2_2Label.Text = 'n';
            app.Soal3Tab = uitab(app.TabGroup);
            app.Soal3Tab.Title = 'Soal 3';
            app.UIAxes3_Output = uiaxes(app.Soal3Tab);
            title(app.UIAxes3_Output, 'Output')
            app.UIAxes3_Input = uiaxes(app.Soal3Tab);
            title(app.UIAxes3_Input, 'Input')
            app.srcEditField3Label = uilabel(app.Soal3Tab);
            app.srcEditField3Label.Text = 'src';
            app.OperationDropDown3Label = uilabel(app.Soal3Tab);
            app.OperationDropDown3Label.Text = 'Operation';
            app.nEditField3Label = uilabel(app.Soal3Tab);
            app.nEditField3Label.Visible = 'off';
            app.nEditField3Label.Text = 'n';
            app.nEditField3 = uieditfield(app.Soal3Tab, 'numeric');
            app.nEditField3.RoundFractionalValues = 'on';
            app.nEditField3.ValueDisplayFormat = '%g';
            app.nEditField3.AllowEmpty = 'on';
            app.nEditField3.Visible = 'off';
            app.nEditField3.Value = 1;
            app.D0EditField3Label = uilabel(app.Soal3Tab);
            app.D0EditField3Label.Text = 'D0';
            app.D0EditField3 = uieditfield(app.Soal3Tab, 'numeric');
            app.D0EditField3.RoundFractionalValues = 'on';
            app.D0EditField3.ValueDisplayFormat = '%g';
            app.D0EditField3.AllowEmpty = 'on';
            app.D0EditField3.ValueChangedFcn = createCallbackFcn(app, @Section3EditFieldValueChanged, true);
            app.D0EditField3.Value = 50;
            app.OperationDropDown3 = uidropdown(app.Soal3Tab);
            app.OperationDropDown3.Items = {'IHPF', 'GHPF', 'BHPF'};
            app.OperationDropDown3.ValueChangedFcn = createCallbackFcn(app, @OperationDropDown3ValueChanged, true);
            app.OperationDropDown3.Value = 'IHPF';
            app.PilihGambarButton3 = uibutton(app.Soal3Tab, 'push');
            app.PilihGambarButton3.ButtonPushedFcn = createCallbackFcn(app, @PilihGambarButton3Pushed, true);
            app.PilihGambarButton3.Text = 'Pilih Gambar';
            app.srcEditField3 = uieditfield(app.Soal3Tab, 'text');
            app.Soal4Tab = uitab(app.TabGroup);
            app.Soal4Tab.Title = 'Soal 4';
            app.Soal5Tab = uitab(app.TabGroup);
            app.Soal5Tab.Title = 'Soal 5';
            app.Soal6Tab = uitab(app.TabGroup);
            app.Soal6Tab.Title = 'Soal 6';
            app.Soal7Tab = uitab(app.TabGroup);
            app.Soal7Tab.Title = 'Soal 7';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end